classdef LIFUControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LIFUDeliveryUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IsppaWcm2Spinner                matlab.ui.control.Spinner
        IsppaWcm2Label                  matlab.ui.control.Label
        TanktestCheckBox                matlab.ui.control.CheckBox
        ThermalIndexSkin                matlab.ui.control.Label
        TIskinLabel                     matlab.ui.control.Label
        ThermalIndexSkull               matlab.ui.control.Label
        TICLabel                        matlab.ui.control.Label
        ThernalIndex                    matlab.ui.control.Label
        TIbrainLabel                    matlab.ui.control.Label
        MechanicalIndex                 matlab.ui.control.Label
        MILabel                         matlab.ui.control.Label
        FocaldiameterDropDown           matlab.ui.control.DropDown
        FocaldiameterDropDownLabel      matlab.ui.control.Label
        NumberCycles                    matlab.ui.control.Label
        NumbercyclesperrepetitionLabel  matlab.ui.control.Label
        DurationsDropDown               matlab.ui.control.DropDown
        DurationsLabel                  matlab.ui.control.Label
        DutyCycleDropDown               matlab.ui.control.DropDown
        DutyCycleLabel                  matlab.ui.control.Label
        PRFHzDropDown                   matlab.ui.control.DropDown
        PRFHzDropDownLabel              matlab.ui.control.Label
        Operator                        matlab.ui.control.Label
        OperatorLabel                   matlab.ui.control.Label
        Ispta                           matlab.ui.control.Label
        IsptaWcm2Label                  matlab.ui.control.Label
        Date                            matlab.ui.control.Label
        DateLabel                       matlab.ui.control.Label
        IDLabel                         matlab.ui.control.Label
        IDPatientLabel                  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        PREPAREPROTOCOLButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        DateProcedure = now % date when application is being run
        Config = struct % config on frequency and min/max values to adjust in GUI
        bFirstPlot = true % this helps to only refresh image data instead of creating a new figure
        hIntensityMap = 0
        hThermalMap = 0
    end
    
    methods (Access = private)
        
        function UpdateDeliveryParameters(app)     
              AppUpdateDeliveryParameters(app);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.LIFUDeliveryUIFigure,'visible','off');
            app.DateProcedure = now;
            app.Date.Text=datestr(app.DateProcedure,'dd-mmm-yyyy');
            
            %%% We ask for ID of patient and Name of operator
            prompt = {'ID of Patient (e.g. "ID001"):',
                      'Name of Operator:'};
            dlgtitle = 'LIFU Study';
            dims = [1 35];
            definput = {'LIFU1-01','Ali'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                msgbox("User cancelling operation, closing app...");
                app.LIFUDeliveryUIFigureCloseRequest();
                return;
            end
            app.IDLabel.Text=answer{1};
            app.Operator.Text = answer{2};
            movegui(app.LIFUDeliveryUIFigure, 'center');
            set(app.LIFUDeliveryUIFigure,'visible','on');
            %we load config and update input elements
            app.Config =DefaultConfig(app,app.IDLabel.Text);
            
            if isempty(app.Config)
                app.LIFUDeliveryUIFigureCloseRequest();
                return
            end
            
            app.IsppaWcm2Spinner.Limits=[app.Config.MinIsppa,app.Config.MaxIsppa];

            set(app.PRFHzDropDown,'Items',app.Config.PRFValues);
            set(app.DutyCycleDropDown,'Items',app.Config.DutyCyleValues);
            set(app.DurationsDropDown,'Items',app.Config.StimulationTimes);
            %we update output elements
            set(app.LIFUDeliveryUIFigure,'Name',sprintf('LIFU Delivery - Frequency = %i kHz',app.Config.USFrequency/1e3));
            app.UpdateDeliveryParameters()
        end

        % Value changed function: PRFHzDropDown
        function PRFHzDropDownValueChanged(app, event)
            app.UpdateDeliveryParameters();
            
        end

        % Value changed function: DutyCycleDropDown
        function DutyCycleDropDownValueChanged(app, event)
            app.UpdateDeliveryParameters();
        end

        % Callback function
        function IsppaWcm2DropDownValueChanged(app, event)
            app.UpdateDeliveryParameters();
        end

        % Close request function: LIFUDeliveryUIFigure
        function LIFUDeliveryUIFigureCloseRequest(app, event)
delete(app)

        end

        % Value changed function: FocaldiameterDropDown
        function FocaldiameterDropDownValueChanged(app, event)
            app.UpdateDeliveryParameters();
            
        end

        % Button pushed function: PREPAREPROTOCOLButton
        function PREPAREPROTOCOLButtonPushed(app, event)
                PrepareProtocol(app);
        end

        % Value changed function: IsppaWcm2Spinner
        function IsppaWcm2SpinnerValueChanged(app, event)
            app.UpdateDeliveryParameters();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LIFUDeliveryUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {416, 416};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1114, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LIFUDeliveryUIFigure = uifigure('Visible', 'off');
            app.LIFUDeliveryUIFigure.Name = 'LIFU Delivery';
            app.LIFUDeliveryUIFigure.CloseRequestFcn = createCallbackFcn(app, @LIFUDeliveryUIFigureCloseRequest, true);
            app.LIFUDeliveryUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LIFUDeliveryUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Isppa (W/cm^2)')
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Z (mm)')
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Temperature (^{\circ}C)')
            xlabel(app.UIAxes2, 'X (mm)')
            ylabel(app.UIAxes2, 'Z (mm)')
            app.IDPatientLabel = uilabel(app.LeftPanel);
            app.IDPatientLabel.Text = 'ID Patient:';
            app.IDLabel = uilabel(app.LeftPanel);
            app.IDLabel.Text = 'ID001';
            app.DateLabel = uilabel(app.LeftPanel);
            app.DateLabel.Text = 'Date:';
            app.Date = uilabel(app.LeftPanel);
            app.Date.Text = 'Nov 1st, 2021';
            app.IsptaWcm2Label = uilabel(app.LeftPanel);
            app.IsptaWcm2Label.Text = 'Ispta (W/cm2) =';
            app.Ispta = uilabel(app.LeftPanel);
            app.Ispta.Text = '1';
            app.OperatorLabel = uilabel(app.LeftPanel);
            app.OperatorLabel.Text = 'Operator:';
            app.Operator = uilabel(app.LeftPanel);
            app.Operator.Text = 'unknown';
            app.PRFHzDropDownLabel = uilabel(app.LeftPanel);
            app.PRFHzDropDownLabel.Text = 'PRF (Hz)';
            app.PRFHzDropDown = uidropdown(app.LeftPanel);
            app.PRFHzDropDown.Items = {'500', '1000', '1500'};
            app.PRFHzDropDown.ValueChangedFcn = createCallbackFcn(app, @PRFHzDropDownValueChanged, true);
            app.PRFHzDropDown.Value = '500';
            app.DutyCycleLabel = uilabel(app.LeftPanel);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleDropDown = uidropdown(app.LeftPanel);
            app.DutyCycleDropDown.Items = {'30', '60'};
            app.DutyCycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DutyCycleDropDownValueChanged, true);
            app.DutyCycleDropDown.Value = '30';
            app.DurationsLabel = uilabel(app.LeftPanel);
            app.DurationsLabel.Text = 'Duration (s)';
            app.DurationsDropDown = uidropdown(app.LeftPanel);
            app.DurationsDropDown.Items = {'40'};
            app.DurationsDropDown.Value = '40';
            app.NumbercyclesperrepetitionLabel = uilabel(app.LeftPanel);
            app.NumbercyclesperrepetitionLabel.Text = 'Number cycles per repetition =';
            app.NumberCycles = uilabel(app.LeftPanel);
            app.NumberCycles.Text = '1';
            app.FocaldiameterDropDownLabel = uilabel(app.LeftPanel);
            app.FocaldiameterDropDownLabel.Text = 'Focal diameter';
            app.FocaldiameterDropDown = uidropdown(app.LeftPanel);
            app.FocaldiameterDropDown.Items = {'Narrow', 'Broad'};
            app.FocaldiameterDropDown.ValueChangedFcn = createCallbackFcn(app, @FocaldiameterDropDownValueChanged, true);
            app.FocaldiameterDropDown.Value = 'Narrow';
            app.MILabel = uilabel(app.LeftPanel);
            app.MILabel.Text = 'MI =';
            app.MechanicalIndex = uilabel(app.LeftPanel);
            app.MechanicalIndex.Text = '1';
            app.TIbrainLabel = uilabel(app.LeftPanel);
            app.TIbrainLabel.Text = 'TI-brain =';
            app.ThernalIndex = uilabel(app.LeftPanel);
            app.ThernalIndex.Text = '1';
            app.TICLabel = uilabel(app.LeftPanel);
            app.TICLabel.Text = 'TIC =';
            app.ThermalIndexSkull = uilabel(app.LeftPanel);
            app.ThermalIndexSkull.Text = '1';
            app.TIskinLabel = uilabel(app.LeftPanel);
            app.TIskinLabel.Text = 'TI-skin =';
            app.ThermalIndexSkin = uilabel(app.LeftPanel);
            app.ThermalIndexSkin.Text = '1';
            app.TanktestCheckBox = uicheckbox(app.LeftPanel);
            app.TanktestCheckBox.Text = 'Tank test';
            app.IsppaWcm2Label = uilabel(app.LeftPanel);
            app.IsppaWcm2Label.Text = 'Isppa (W/cm2)';
            app.IsppaWcm2Spinner = uispinner(app.LeftPanel);
            app.IsppaWcm2Spinner.Step = 0.25;
            app.IsppaWcm2Spinner.Limits = [0 30];
            app.IsppaWcm2Spinner.ValueDisplayFormat = '%3.2f';
            app.IsppaWcm2Spinner.ValueChangedFcn = createCallbackFcn(app, @IsppaWcm2SpinnerValueChanged, true);
            app.IsppaWcm2Spinner.Value = 4;
            app.RightPanel = uipanel(app.GridLayout);
            app.PREPAREPROTOCOLButton = uibutton(app.RightPanel, 'push');
            app.PREPAREPROTOCOLButton.ButtonPushedFcn = createCallbackFcn(app, @PREPAREPROTOCOLButtonPushed, true);
            app.PREPAREPROTOCOLButton.Text = {'PREPARE'; 'PROTOCOL '};
            % Show the figure after all components are created
            app.LIFUDeliveryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIFUControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LIFUDeliveryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LIFUDeliveryUIFigure)
        end
    end
end